classdef ozonegraph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        AdquisicinTab              matlab.ui.container.Tab
        MonitorLamp1               matlab.ui.control.Lamp
        MonitorLabel1              matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        EditField                  matlab.ui.control.NumericEditField
        SalirButton1               matlab.ui.control.Button
        IniciciarButton            matlab.ui.control.StateButton
        Panel                      matlab.ui.container.Panel
        TiempoEditField            matlab.ui.control.NumericEditField
        TiempoLabel                matlab.ui.control.Label
        ArduinoDropDownLabel       matlab.ui.control.Label
        ArduinoDropDown            matlab.ui.control.DropDown
        FormatoTiempoDropDown1     matlab.ui.control.DropDown
        GananciaSpinnerLabel       matlab.ui.control.Label
        GananciaSpinner            matlab.ui.control.Spinner
        UIAxes                     matlab.ui.control.UIAxes
        VisorTab                   matlab.ui.container.Tab
        TituloEditField            matlab.ui.control.EditField
        SubtituloEditField         matlab.ui.control.EditField
        Panel_2                    matlab.ui.container.Panel
        OzonoenLabel               matlab.ui.control.Label
        ConsumidoLabel             matlab.ui.control.Label
        ConsumidoEditField         matlab.ui.control.NumericEditField
        ResidualLabel              matlab.ui.control.Label
        ResidualEditField          matlab.ui.control.NumericEditField
        TotalLabel                 matlab.ui.control.Label
        TotalEditField             matlab.ui.control.NumericEditField
        gNm3Label                  matlab.ui.control.Label
        gNm3Label_2                matlab.ui.control.Label
        gNm3Label_3                matlab.ui.control.Label
        enLabel                    matlab.ui.control.Label
        TiempoOzonoLabel           matlab.ui.control.Label
        Panel_3                    matlab.ui.container.Panel
        GridCheckBox               matlab.ui.control.CheckBox
        ColordelineaDropDownLabel  matlab.ui.control.Label
        ColorDropDown              matlab.ui.control.DropDown
        SalirButton2               matlab.ui.control.Button
        GuardarButton              matlab.ui.control.Button
        MonitorLamp2               matlab.ui.control.Lamp
        MonitorLabel2              matlab.ui.control.Label
        AbrirButton                matlab.ui.control.Button
        UnidadesDropDown           matlab.ui.control.DropDown
        FormatoTiempoDropDown2     matlab.ui.control.DropDown
        ArchivoDropDown            matlab.ui.control.DropDown
        UIAxes2                    matlab.ui.control.UIAxes
        SensordeOzonoBMT960Label   matlab.ui.control.Label
        Ozonogramav21Label         matlab.ui.control.Label
        Buildv2109Label            matlab.ui.control.Label
    end

    
    properties (Access = private)
        ports % Description
        format
        flag
        timelength
        t
        oz
        datamatrix
        a % objeto arduino
        area_bc % area bajo la curva
        area_sc % area sobre la curva  = max(x)*max(y) - area_bc
        area_t % area total = area_bc + area_sc
        f % nombre de archivo
        path % dirección de carpeta
        tab % para table()
    end
    
    methods (Access = public)
        
        %         function updateDialog(app, x, y, z)
        %             app.name = x;
        %             app.format = y;
        %             app.flag = z;
        %             disp("Campos enviados")
        %             disp("Archivo updateDialog(): " + app.name + app.format)
        %             disp("Flag updateDialog(): " + app.flag)
        %             ArduinoData(app);
        %         end
    end
    
    methods (Access = private)
        
        function ArduinoData(app)
            % app.VisorTab.Parent = [];
            if app.flag == 0
                clear app.t app.oz;
                app.ArduinoDropDown.Enable = 'off';
                app.TiempoEditField.Enable = 'off';
                app.FormatoTiempoDropDown1.Enable = 'off';
                app.oz = 0;
                app.t = 0;
                app.datamatrix = [];
                app.MonitorLamp1.Color = 'green';
                pause(1)
                app.IniciciarButton.Text = 'Preparando...';
                app.MonitorLamp1.Color = '#edb120';
                app.UIAxes.XLim = [0 app.timelength];
                plot(app.UIAxes,app.oz);
                flush(app.a)
                app.IniciciarButton.Text = 'Detener';
                app.MonitorLabel1.BackgroundColor = 'black';
                app.MonitorLabel1.FontColor = '#EDB120';
                app.MonitorLabel1.Text = '>> Adquiriendo datos...';
                app.EditField.Value = 0;
                for i=1:app.timelength*10
                    app.t(i) = i/10;
                    app.oz(i) = app.a.analogRead(0)*(600/1023)*app.GananciaSpinner.Value;
                    app.EditField.Value = app.oz(i);
                    plot(app.UIAxes,app.t,app.oz);
                    pause(0.1);
                    if app.IniciciarButton.Value == 0
                        % app.flag = 0;
                        app.MonitorLamp1.Color = '#EDB120';
                        app.IniciciarButton.Enable = 'off';
                        app.IniciciarButton.Text = 'Deteniendo...';
                        app.MonitorLabel1.BackgroundColor = 'black';
                        app.MonitorLabel1.FontColor = '#EDB120';
                        app.MonitorLabel1.Text = '>> Proceso interrumpido!';
                        app.flag = 1;
                        pause(3)
                        break;
                    end
                end
                app.datamatrix = [app.t', app.oz'];
                % er1 = [app.t; app.oz];
                % assignin('base', 'data', app.data)
                % assignin('base', 'e1', er1)
                %                 assignin('base', 'time', app.t)
                %                 assignin('base', 'conc', app.oz)
                % disp(app.data)
                app.MonitorLabel1.BackgroundColor = 'black';
                app.MonitorLabel1.FontColor = '#EDB120';
                app.MonitorLabel1.Text = '>> Preparando datos, espere...';
                % funcion para guardar archivos
                % saveDataFunc(app, app.t, app.oz);
                pause(2)
                app.MonitorLamp1.Color = 'green';
                app.IniciciarButton.Text = 'Listo!';
                app.MonitorLabel1.BackgroundColor = 'black';
                app.MonitorLabel1.FontColor = 'green';
                app.MonitorLabel1.Text = '>> Listo!!!';
                pause(2)
                % app.GuardarButton.Enable = 'on';
                app.IniciciarButton.Enable = 'on';
                app.IniciciarButton.Value = 0;
                app.IniciciarButton.Text = 'Iniciar';
                app.ArduinoDropDown.Enable = 'on';
                app.TiempoEditField.Enable = 'on';
                app.FormatoTiempoDropDown1.Enable = 'on';
                app.MonitorLabel1.BackgroundColor = 'none';
                app.MonitorLabel1.Text = '';
                app.flag = 1;
                app.MonitorLamp2.Color = 'green';
                app.VisorTab.Parent = app.TabGroup;
                visorPlot(app, app.t, app.oz);
                pause(3)
            end
        end
        
        function saveDataFunc(app, file, path, time, conc, consumido, residual, total)
            if app.format == ".mat"
                data = [time;conc];
                save(fullfile(path, file), 'data', 'consumido', 'residual', 'total', '-mat', '-v7.3');
            elseif app.format == ".csv" || app.format == ".txt"
                fileID = fopen(app.file, 'w');
                fprintf(fileID,"Ozono\n");
                fprintf(fileID,"Consumido: %.2f\n", consumido);
                fprintf(fileID,"Residual: %.2f\n", residual);
                fprintf(fileID,"Total: %.2f\n", total);
                fprintf(fileID,"\n");
                fprintf(fileID,"Tiempo (s), Concentracion (g/Nm^3)\n");
                writematrix(app.datamatrix, app.file, "WriteMode","append");
                fclose(fileID);
            elseif app.format == ".xls"
                xlswrite(app.file, {'Ozono', ''; 'Consumido: ', consumido; 'Residual: ', residual; 'Total: ', total}, 1, "A1");
                xlswrite(app.file,{'Tiempo (s)', 'Concentracion (g/Nm^3)'}, 1, 'A6');
                xlswrite(app.file, app.datamatrix, 1, 'A7');
                %                 writematrix(app.datamatrix, app.file, "WriteMode","append");
            end
            
        end
        
        function visorPlot(app, t, o)
            if app.t == 0
                app.UIAxes2.YLim = [0 40];
                app.UIAxes2.XLim = [0 1];
            else
                app.area_bc = trapz(t,o);
                app.area_sc = max(t)*max(o) - app.area_bc;
                app.area_t = app.area_bc + app.area_sc;
                app.area_bc = app.area_bc/60;
                app.area_sc = app.area_sc/60;
                app.area_t = app.area_t/60;
                UnidadesDropDownValueChanged(app);
                app.UIAxes2.YLim = [0 40];
                value = app.FormatoTiempoDropDown2.Value;
                if value == "seg"
                    app.UIAxes2.XLabel.String = "Tiempo (s)";
                    app.TiempoOzonoLabel.Text = num2str(max(app.t), '%.2f');
                    app.UIAxes2.XLim = [0 max(app.t)];
                    for i=1:length(app.UIAxes2.XTick)
                        if max(app.t) <= 5
                            app.UIAxes2.XTickLabel{i} = num2str(app.UIAxes2.XTick(i),'%.1f');
                        else
                            app.UIAxes2.XTickLabel{i} = num2str(round(app.UIAxes2.XTick(i)));
                        end
                    end
                end
                if value == "min"
                    app.UIAxes2.XLabel.String = "Tiempo (min)";
                    app.TiempoOzonoLabel.Text = num2str(max(app.t)/60, '%.2f');
                    app.UIAxes2.XLim = [0 max(app.t)];
                    for i=1:length(app.UIAxes2.XTick)
                        if max(app.t) <= 9*60
                            app.UIAxes2.XTickLabel{i} = num2str(app.UIAxes2.XTick(i)/60,'%.1f');
                        else
                            app.UIAxes2.XTickLabel{i} = num2str(round(app.UIAxes2.XTick(i)/60));
                        end
                    end
                end
            end
            plot(app.UIAxes2,t,o);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            % app.VisorTab.Parent = [];
            app.MonitorLamp1.Color = 'red';
            app.MonitorLamp2.Color = 'green';
            app.IniciciarButton.Text = 'Conectar';
            app.ArduinoDropDown.Enable = 'off';
            app.TiempoEditField.Enable = 'off';
            app.FormatoTiempoDropDown1.Enable = 'off';
            app.GananciaSpinner.Enable = 'off';
            app.area_bc = 0;
            app.area_sc = 0;
            app.area_t = 0;
            app.t = 0;
            app.oz = 0;
            % app.timelength = app.TiempoEditField.Value;
            FormatoTiempoDropDown1ValueChanged(app);
            UnidadesDropDownValueChanged(app);
            FormatoTiempoDropDown2ValueChanged(app);
        end

        % Button pushed function: SalirButton1
        function SalirButton1Pushed(app, event)
            clearvars app.a app.datamatrix app.t app.oz;
            delete(app)
        end

        % Value changed function: IniciciarButton
        function IniciciarButtonValueChanged(app, event)
            app.ports = char(serialportlist);
            if isempty(app.ports) == 1 && isempty(app.a) == 1
                app.IniciciarButton.Enable = 'off';
                app.IniciciarButton.Text = 'Error';
                app.MonitorLamp1.Color = 'red';
%                 app.MonitorLamp2.Color = 'red';
                %                 app.MonitorLabel1.BackgroundColor = 'none';
                app.MonitorLabel1.Text = '';
                pause(0.5)
                app.MonitorLabel1.FontColor = 'red';
                app.MonitorLabel1.BackgroundColor = 'black';
                app.MonitorLabel1.Text = '>> Error de comunicación, verifique la conexión!!!';
                pause(0.5)
                %                 app.MonitorLabel1.BackgroundColor = 'none';
                app.MonitorLabel1.Text = '';
                pause(0.5)
                %                 app.MonitorLabel1.BackgroundColor = 'black';
                app.MonitorLabel1.Text = '>> Error de comunicación, verifique la conexión!!!';
                pause(0.5)
                %                 app.MonitorLabel1.BackgroundColor = 'none';
                app.MonitorLabel1.Text = '';
                pause(0.5)
                %                 app.MonitorLabel1.BackgroundColor = 'black';
                app.MonitorLabel1.Text = '>> Error de comunicación, verifique la conexión!!!';
                app.IniciciarButton.Enable = 'on';
                app.IniciciarButton.Value = 0;
                app.IniciciarButton.Text = 'Conectar';
                app.ArduinoDropDown.Enable = 'off';
                app.TiempoEditField.Enable = 'off';
                app.FormatoTiempoDropDown1.Enable = 'off';
                app.GananciaSpinner.Enable = 'off';
                app.flag = 1;
            elseif isempty(app.ports) == 1 && isempty(app.a) == 0
                %                 delete(instrfindall())
                %                 delete(app.a)
                fclose(instrfindall());
                clear app.a
                app.a = [];
                app.IniciciarButton.Enable = 'off';
                app.IniciciarButton.Text = 'Error';
                app.MonitorLamp1.Color = 'red';
%                 app.MonitorLamp2.Color = 'red';
                %                 app.MonitorLabel1.BackgroundColor = 'none';
                app.MonitorLabel1.Text = '';
                pause(0.5)
                app.MonitorLabel1.FontColor = 'red';
                app.MonitorLabel1.BackgroundColor = 'black';
                app.MonitorLabel1.Text = '>> Error de comunicación, verifique la conexión!!!';
                pause(0.5)
                %                 app.MonitorLabel1.BackgroundColor = 'none';
                app.MonitorLabel1.Text = '';
                pause(0.5)
                %                 app.MonitorLabel1.BackgroundColor = 'black';
                app.MonitorLabel1.Text = '>> Error de comunicación, verifique la conexión!!!';
                pause(0.5)
                %                 app.MonitorLabel1.BackgroundColor = 'none';
                app.MonitorLabel1.Text = '';
                pause(0.5)
                %                 app.MonitorLabel1.BackgroundColor = 'black';
                app.MonitorLabel1.Text = '>> Error de comunicación, verifique la conexión!!!';
                app.IniciciarButton.Enable = 'on';
                app.IniciciarButton.Value = 0;
                app.IniciciarButton.Text = 'Conectar';
                app.ArduinoDropDown.Enable = 'off';
                app.TiempoEditField.Enable = 'off';
                app.FormatoTiempoDropDown1.Enable = 'off';
                app.GananciaSpinner.Enable = 'off';
                app.flag = 1;
            elseif isempty(app.ports) == 0 && isempty(app.a) == 1
                app.ArduinoDropDown.Items = [app.ArduinoDropDown.Items app.ports];
                app.VisorTab.Parent = [];
                app.IniciciarButton.Enable = 'off';
                app.IniciciarButton.Text = 'Conectando...';
                app.MonitorLamp1.Color = '#edb120';
%                 app.MonitorLamp2.Color = 'red';
                app.ArduinoDropDown.Enable = 'off';
                app.TiempoEditField.Enable = 'off';
                app.FormatoTiempoDropDown1.Enable = 'off';
                app.GananciaSpinner.Enable = 'off';
                app.MonitorLabel1.BackgroundColor = 'black';
                app.MonitorLabel1.FontColor = '#edb120';
                app.MonitorLabel1.Text = strcat(">> Conectando Arduino al puerto " + app.ArduinoDropDown.Value + ", espere....");
                app.a = arduino(app.ArduinoDropDown.Value);
                app.MonitorLamp1.Color = 'green';
%                 app.MonitorLamp2.Color = 'green';
                app.MonitorLabel1.BackgroundColor = 'black';
                app.MonitorLabel1.FontColor = 'green';
                app.MonitorLabel1.Text = '>> Arduino listo!!!';
                app.IniciciarButton.Text = 'Listo!';
                pause(3)
                app.IniciciarButton.Enable = 'on';
                app.IniciciarButton.Value = 0;
                app.IniciciarButton.Text = 'Iniciar';
                app.MonitorLabel1.BackgroundColor = 'none';
                app.MonitorLabel1.Text = '';
                app.ArduinoDropDown.Enable = 'on';
                app.TiempoEditField.Enable = 'on';
                app.FormatoTiempoDropDown1.Enable = 'on';
                app.GananciaSpinner.Enable = 'on';
                app.VisorTab.Parent = app.TabGroup;
                app.flag = 0;
            elseif isempty(app.ports) == 0 && isempty(app.a) == 0
                app.flag = 0;
            end
            if app.flag == 0 && app.IniciciarButton.Value == 1
                app.VisorTab.Parent = [];
                %                 app.ArchivoLabel.Text = '';
                app.NombreArchivo.Value = '';
                ArduinoData(app);
            end
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            time = app.t;
            conc = app.oz;
            app.datamatrix = [app.t', app.oz'];
            consumido = app.area_sc;
            residual = app.area_bc;
            total = app.area_t;
            app.GuardarButton.Enable = 'off';
%             app.MonitorLamp1.Color = '#edb120';
            app.MonitorLamp2.Color = '#edb120';
            app.MonitorLabel2.BackgroundColor = 'black';
            app.MonitorLabel2.FontColor = '#edb120';
            app.MonitorLabel2.Text = '>> Guardando...';
            pause(1)
            % considerar la siguiente forma:
            [app.f,app.path,index] = uiputfile({'*.mat', 'MAT-files (*.mat)'; '*.xlsx', 'Excel (*.xlsx)'; '*.csv', 'Archivo CSV (*.csv)'; '*.txt', 'Archivo de texto (*.txt)'}, 'Guardar como');
            if isequal(app.f,0) || isequal(app.path,0)
                %                 disp('User clicked Cancel.')
                app.MonitorLamp2.Color = 'green';
                app.MonitorLabel2.FontColor = 'green';
                app.MonitorLabel2.Text = '>> Ok!!!';
                pause(1)
                app.MonitorLabel2.BackgroundColor = 'none';
                app.MonitorLabel2.Text = '';
                app.GuardarButton.Enable = 'on';
            else
                %                 disp(['User selected ',fullfile(path,f),...
                %                     ' and then clicked Save.'])
                if index == 1
                    data = [time;conc];
                    save(fullfile(app.path, app.f), 'data', 'consumido', 'residual', 'total', '-mat', '-v7.3');
                elseif index == 2
                    xlswrite(fullfile(app.path,app.f), {'Ozono', ''; 'Consumido: ', consumido; 'Residual: ', residual; 'Total: ', total}, 1, 'A1');
                    xlswrite(fullfile(app.path,app.f), {'Tiempo (s)', 'Concentracion (g/Nm^3)'}, 1, 'A6');
                    xlswrite(fullfile(app.path,app.f), app.datamatrix, 1, 'A7');
%                     writematrix(app.datamatrix, fullfile(path,f), "WriteMode","append");
                elseif index == 3 || index == 4
                    fileID = fopen(fullfile(app.path,app.f), 'w');
                    fprintf(fileID,"Ozono\n");
                    fprintf(fileID,"Consumido: %.2f\n", consumido);
                    fprintf(fileID,"Residual: %.2f\n", residual);
                    fprintf(fileID,"Total: %.2f\n", total);
                    fprintf(fileID,"\n");
                    fprintf(fileID,"Tiempo (s), Concentracion (g/Nm^3)\n");
                    writematrix(app.datamatrix, fullfile(app.path, app.f), "WriteMode","append");
                    fclose(fileID);
                end
                %                 app.MonitorLamp1.Color = 'green';
                app.MonitorLamp2.Color = 'green';
                app.MonitorLabel2.FontColor = 'green';
                app.MonitorLabel2.Text = '>> Listo!!!';
                pause(1)
                app.MonitorLabel2.BackgroundColor = 'none';
                app.MonitorLabel2.Text = '';
                app.GuardarButton.Enable = 'on';
            end
            % tomado de https://www.mathworks.com/help/matlab/ref/uiputfile.html
            % [filename, pathname, filterindex] = uiputfile( ...
            % {'*.m;*.fig;*.mat;*.slx;*.mdl',...
            %  'MATLAB Files (*.m,*.mlx,*.fig,*.mat,*.slx,*.mdl)';
            %  '*.m;*.mlx', 'program files (*.m,*.mlx)';...
            %  '*.fig','Figures (*.fig)';...
            %  '*.mat','MAT-files (*.mat)';...
            %  '*.slx;*.mdl','Models (*.slx,*.mdl)';...
            %  '*.*',  'All Files (*.*)'});
            
            %             saveDataFunc(app, app.t, app.oz, app.area_sc, app.area_bc, app.area_t);
        end

        % Button pushed function: SalirButton2
        function SalirButton2Pushed(app, event)
            clearvars app.a app.datamatrix app.t app.oz;
            delete(app)
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            value = app.GridCheckBox.Value;
            app.UIAxes2.XGrid = value;
            app.UIAxes2.YGrid = value;
        end

        % Value changed function: TituloEditField
        function TituloEditFieldValueChanged(app, event)
            app.UIAxes2.Title.String = app.TituloEditField.Value;
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
            if value == "azul"
                app.UIAxes2.Children(1).Color = '#0072BD';
            end
            if value == "naranja"
                app.UIAxes2.Children(1).Color = '#D95319';
            end
            if value == "amarillo"
                app.UIAxes2.Children(1).Color = '#EDB120';
            end
            if value == "morado"
                app.UIAxes2.Children(1).Color = '#7E2F8E';
            end
            if value == "verde"
                app.UIAxes2.Children(1).Color = '#77AC30';
            end
            if value == "cian"
                app.UIAxes2.Children(1).Color = '#4DBEEE';
            end
            if value == "rojo"
                app.UIAxes2.Children(1).Color = '#A2142F';
            end
        end

        % Value changed function: FormatoTiempoDropDown1
        function FormatoTiempoDropDown1ValueChanged(app, event)
            value = app.FormatoTiempoDropDown1.Value;
            if value == "seg"
                app.timelength = app.TiempoEditField.Value;
                app.UIAxes.XLim = [0 app.timelength];
                app.UIAxes.XLabel.String = "Tiempo (s)";
                for i=1:length(app.UIAxes.XTick)
                    if app.TiempoEditField.Value <= 5
                        app.UIAxes.XTickLabel{i} = num2str(app.UIAxes.XTick(i),'%.1f');
                    else
                        app.UIAxes.XTickLabel{i} = num2str(round(app.UIAxes.XTick(i)));
                    end
                end
            end
            if value == "min"
                app.timelength = app.TiempoEditField.Value*60;
                app.UIAxes.XLim = [0 app.timelength];
                app.UIAxes.XLabel.String = "Tiempo (min)";
                for i=1:length(app.UIAxes.XTick)
                    if app.TiempoEditField.Value <= 9
                        app.UIAxes.XTickLabel{i} = num2str(app.UIAxes.XTick(i)/60,'%.1f');
                    else
                        app.UIAxes.XTickLabel{i} = num2str(round(app.UIAxes.XTick(i)/60));
                    end
                end
            end
        end

        % Value changed function: SubtituloEditField
        function SubtituloEditFieldValueChanged(app, event)
            app.UIAxes2.Subtitle.String = app.SubtituloEditField.Value;
        end

        % Value changed function: TiempoEditField
        function TiempoEditFieldValueChanged(app, event)
            FormatoTiempoDropDown1ValueChanged(app, event);
            % app.UIAxes.XLim = [0 app.timelength];
        end

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
            app.t = 0;
            app.oz = 0;
            [app.f, app.path] = uigetfile({'*.mat'},'Seleccionar Archivo');
            % Revisar y corregir:
            % [file,path,indx] = uigetfile( ...
            % {'*.m;*.mlx;*.fig;*.mat;*.slx;*.mdl',...
            %     'MATLAB Files (*.m,*.mlx,*.fig,*.mat,*.slx,*.mdl)';
            %    '*.m;*.mlx','Code files (*.m,*.mlx)'; ...
            %    '*.fig','Figures (*.fig)'; ...
            %    '*.mat','MAT-files (*.mat)'; ...
            %    '*.mdl;*.slx','Models (*.slx, *.mdl)'; ...
            %    '*.*',  'All Files (*.*)'}, ...
            %    'Select a File');
            
            % [file,path,indx] = uigetfile;
            % if isequal(file,0)
            %    disp('User selected Cancel')
            % else
            %    disp(['User selected ', fullfile(path, file),...
            %          ' and filter index: ', num2str(indx)])
            % end
            if app.f == 0
                
            else
                %                 fileopen = [path f];
                varlist = who('-file', fullfile(app.path, app.f));
                if length(varlist) > 1
                    dt = importdata(fullfile(app.path, app.f));
%                     app.MonitorLamp1.Color = '#edb120';
                    app.MonitorLamp2.Color = '#edb120';
                    app.MonitorLabel2.BackgroundColor = 'black';
                    app.MonitorLabel2.FontColor = '#edb120';
                    app.MonitorLabel2.Text = '>> Cargando archivo...';
                    pause(1)
                    app.t = dt.('data')(1,:);
                    app.oz = dt.('data')(2,:);
                else
                    dt = importdata(fullfile(app.path, app.f));
                    %                     app.MonitorLamp1.Color = '#edb120';
                    app.MonitorLamp2.Color = '#edb120';
                    app.MonitorLabel2.BackgroundColor = 'black';
                    app.MonitorLabel2.FontColor = '#edb120';
                    app.MonitorLabel2.Text = '>> Cargando archivo...';
                    pause(1)
                    app.t = dt(1,:);
                    app.oz = dt(2,:);
                end
                visorPlot(app, app.t, app.oz);
                %                 app.ArchivoLabel.Text = ['\' f];
                app.NombreArchivo.Value = ['\' app.f];
                %                 app.MonitorLamp1.Color = 'green';
                app.MonitorLamp2.Color = 'green';
                app.MonitorLabel2.BackgroundColor = 'black';
                app.MonitorLabel2.FontColor = 'green';
                app.MonitorLabel2.Text = '>> Listo!!!';
                pause(2)
                app.MonitorLabel2.BackgroundColor = 'none';
                app.MonitorLabel2.Text = '';
            end
        end

        % Value changed function: UnidadesDropDown
        function UnidadesDropDownValueChanged(app, event)
            value = app.UnidadesDropDown.Value;
            app.gNm3Label.Interpreter = 'latex';
            app.gNm3Label_2.Interpreter = 'latex';
            app.gNm3Label_3.Interpreter = 'latex';
            if value == "g/Nm3"
                app.ConsumidoEditField.Value = app.area_sc;
                app.ResidualEditField.Value = app.area_bc;
                app.TotalEditField.Value = app.area_t;
                app.gNm3Label.Text = "$g/Nm^3$";
                app.gNm3Label_2.Text = "$g/Nm^3$";
                app.gNm3Label_3.Text = "$g/Nm^3$";
            else
                app.ConsumidoEditField.Value = app.area_sc/1000;
                app.ResidualEditField.Value = app.area_bc/1000;
                app.TotalEditField.Value = app.area_t/1000;
                app.gNm3Label.Text = "$" + value + "$";
                app.gNm3Label_2.Text = "$" + value + "$";
                app.gNm3Label_3.Text = "$" + value + "$";
            end
        end

        % Value changed function: FormatoTiempoDropDown2
        function FormatoTiempoDropDown2ValueChanged(app, event)
            visorPlot(app, app.t, app.oz);
        end

        % Value changed function: ArchivoDropDown
        function ArchivoDropDownValueChanged(app, event)
            value = app.ArchivoDropDown.Value;
            if length(app.f) > 1
                if value == "Todos"
                    plot(app.UIAxes2, 0);
                    [~, s] = size(app.tab);
                    hold(app.UIAxes2, "on");
                    for i=2:s
                        %                         plot(app.UIAxes2, [1:length(app.T.(i+1)(1:end))], app.T.(i+1));
                        plot(app.UIAxes2, app.tab.(i));
                    end
                    hold(app.UIAxes2, "off");
                else
                    app.area_bc = 0;
                    app.area_sc = 0;
                    app.area_t = 0;
                    app.area_bc = trapz([1:length(app.tab.(value)(1:end))], app.tab.(value));
                    app.area_sc = max([1:length(app.tab.(value)(1:end))])*max(app.tab.(value)) - app.area_bc;
                    app.area_t = app.area_bc + app.area_sc;
                    app.area_bc = app.area_bc/60;
                    app.area_sc = app.area_sc/60;
                    app.area_t = app.area_t/60;
                    UnidadesDropDownValueChanged(app);
                    plot(app.UIAxes2, 0);
                    %                     plot(app.UIAxes2, [1:length(app.T.(value)(1:end))], app.T.(value));
                    plot(app.UIAxes2, app.T.(value));
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AdquisicinTab = uitab(app.TabGroup);
            app.AdquisicinTab.Title = 'Adquisición';
            app.MonitorLamp1 = uilamp(app.AdquisicinTab);
            app.MonitorLabel1 = uilabel(app.AdquisicinTab);
            app.MonitorLabel1.Text = '';
            app.Label_5 = uilabel(app.AdquisicinTab);
            app.EditField = uieditfield(app.AdquisicinTab, 'numeric');
            app.EditField.ValueDisplayFormat = '%.2f g/Nm^3';
            app.EditField.Editable = 'off';
            app.SalirButton1 = uibutton(app.AdquisicinTab, 'push');
            app.SalirButton1.ButtonPushedFcn = createCallbackFcn(app, @SalirButton1Pushed, true);
            app.SalirButton1.Text = 'Salir';
            app.IniciciarButton = uibutton(app.AdquisicinTab, 'state');
            app.IniciciarButton.ValueChangedFcn = createCallbackFcn(app, @IniciciarButtonValueChanged, true);
            app.IniciciarButton.Text = 'Iniciciar';
            app.Panel = uipanel(app.AdquisicinTab);
            app.TiempoEditField = uieditfield(app.Panel, 'numeric');
            app.TiempoEditField.ValueChangedFcn = createCallbackFcn(app, @TiempoEditFieldValueChanged, true);
            app.TiempoEditField.Value = 60;
            app.TiempoLabel = uilabel(app.Panel);
            app.TiempoLabel.Text = 'Tiempo ';
            app.ArduinoDropDownLabel = uilabel(app.Panel);
            app.ArduinoDropDownLabel.Text = 'Arduino';
            app.ArduinoDropDown = uidropdown(app.Panel);
            app.ArduinoDropDown.Items = {};
            app.ArduinoDropDown.Value = {};
            app.FormatoTiempoDropDown1 = uidropdown(app.Panel);
            app.FormatoTiempoDropDown1.Items = {'seg', 'min'};
            app.FormatoTiempoDropDown1.ValueChangedFcn = createCallbackFcn(app, @FormatoTiempoDropDown1ValueChanged, true);
            app.FormatoTiempoDropDown1.Value = 'min';
            app.GananciaSpinnerLabel = uilabel(app.Panel);
            app.GananciaSpinnerLabel.Text = 'Ganancia';
            app.GananciaSpinner = uispinner(app.Panel);
            app.GananciaSpinner.Step = 0.0001;
            app.GananciaSpinner.ValueDisplayFormat = '%.4f';
            app.GananciaSpinner.Value = 0.1674;
            app.UIAxes = uiaxes(app.AdquisicinTab);
            xlabel(app.UIAxes, 'Tiempo (min)')
            ylabel(app.UIAxes, 'Concentración [g/Nm^3]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 40];
            app.VisorTab = uitab(app.TabGroup);
            app.VisorTab.Title = 'Visor';
            app.TituloEditField = uieditfield(app.VisorTab, 'text');
            app.TituloEditField.ValueChangedFcn = createCallbackFcn(app, @TituloEditFieldValueChanged, true);
            app.TituloEditField.Value = 'Titulo';
            app.SubtituloEditField = uieditfield(app.VisorTab, 'text');
            app.SubtituloEditField.ValueChangedFcn = createCallbackFcn(app, @SubtituloEditFieldValueChanged, true);
            app.SubtituloEditField.Value = 'Subtitulo';
            app.Panel_2 = uipanel(app.VisorTab);
            app.OzonoenLabel = uilabel(app.Panel_2);
            app.OzonoenLabel.Text = 'Ozono en';
            app.ConsumidoLabel = uilabel(app.Panel_2);
            app.ConsumidoLabel.Text = 'Consumido';
            app.ConsumidoEditField = uieditfield(app.Panel_2, 'numeric');
            app.ConsumidoEditField.ValueDisplayFormat = '%.2f';
            app.ConsumidoEditField.Editable = 'off';
            app.ResidualLabel = uilabel(app.Panel_2);
            app.ResidualLabel.Text = 'Residual';
            app.ResidualEditField = uieditfield(app.Panel_2, 'numeric');
            app.ResidualEditField.ValueDisplayFormat = '%.2f';
            app.ResidualEditField.Editable = 'off';
            app.TotalLabel = uilabel(app.Panel_2);
            app.TotalLabel.Text = 'Total';
            app.TotalEditField = uieditfield(app.Panel_2, 'numeric');
            app.TotalEditField.ValueDisplayFormat = '%.2f';
            app.TotalEditField.Editable = 'off';
            app.gNm3Label = uilabel(app.Panel_2);
            app.gNm3Label.Text = 'g/Nm^3';
            app.gNm3Label_2 = uilabel(app.Panel_2);
            app.gNm3Label_2.Text = 'g/Nm^3';
            app.gNm3Label_3 = uilabel(app.Panel_2);
            app.gNm3Label_3.Text = 'g/Nm^3';
            app.enLabel = uilabel(app.Panel_2);
            app.enLabel.Text = 'en';
            app.TiempoOzonoLabel = uilabel(app.Panel_2);
            app.TiempoOzonoLabel.Text = '';
            app.Panel_3 = uipanel(app.VisorTab);
            app.GridCheckBox = uicheckbox(app.Panel_3);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = 'Grid';
            app.ColordelineaDropDownLabel = uilabel(app.Panel_3);
            app.ColordelineaDropDownLabel.Text = 'Color de linea';
            app.ColorDropDown = uidropdown(app.Panel_3);
            app.ColorDropDown.Items = {'azul', 'naranja', 'amarillo', 'morado', 'verde', 'cian', 'rojo'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 'azul';
            app.SalirButton2 = uibutton(app.VisorTab, 'push');
            app.SalirButton2.ButtonPushedFcn = createCallbackFcn(app, @SalirButton2Pushed, true);
            app.SalirButton2.Text = 'Salir';
            app.GuardarButton = uibutton(app.VisorTab, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Icon = 'save_icon.png';
            app.GuardarButton.Text = '';
            app.MonitorLamp2 = uilamp(app.VisorTab);
            app.MonitorLabel2 = uilabel(app.VisorTab);
            app.MonitorLabel2.Text = '';
            app.AbrirButton = uibutton(app.VisorTab, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Icon = 'open_icon.png';
            app.AbrirButton.Text = '';
            app.UnidadesDropDown = uidropdown(app.VisorTab);
            app.UnidadesDropDown.Items = {'g/Nm3', 'g/L'};
            app.UnidadesDropDown.ValueChangedFcn = createCallbackFcn(app, @UnidadesDropDownValueChanged, true);
            app.UnidadesDropDown.Value = 'g/Nm3';
            app.FormatoTiempoDropDown2 = uidropdown(app.VisorTab);
            app.FormatoTiempoDropDown2.Items = {'seg', 'min'};
            app.FormatoTiempoDropDown2.ValueChangedFcn = createCallbackFcn(app, @FormatoTiempoDropDown2ValueChanged, true);
            app.FormatoTiempoDropDown2.Value = 'min';
            app.ArchivoDropDown = uidropdown(app.VisorTab);
            app.ArchivoDropDown.Items = {};
            app.ArchivoDropDown.ValueChangedFcn = createCallbackFcn(app, @ArchivoDropDownValueChanged, true);
            app.ArchivoDropDown.Value = {};
            app.UIAxes2 = uiaxes(app.VisorTab);
            xlabel(app.UIAxes2, 'Tiempo (s)')
            ylabel(app.UIAxes2, 'Concentración [g/Nm^3]')
            zlabel(app.UIAxes2, 'Z')
            app.SensordeOzonoBMT960Label = uilabel(app.UIFigure);
            app.SensordeOzonoBMT960Label.Text = 'Sensor de Ozono BMT 960';
            app.Ozonogramav21Label = uilabel(app.UIFigure);
            app.Ozonogramav21Label.Text = 'Ozonograma v2.1';
            app.Buildv2109Label = uilabel(app.UIFigure);
            app.Buildv2109Label.Text = 'Build:v2.1.0.9';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ozonegraph

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end